classdef assignmentApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ParaleterLabel              matlab.ui.control.Label
        Estop_Pushbutton            matlab.ui.control.Button
        NumberofCansEditFieldLabel  matlab.ui.control.Label
        Number_of_Objects           matlab.ui.control.NumericEditField
        Reset_Pushbutton            matlab.ui.control.Button
        Start_Pushbutton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        safetyClass Safety_Circuit;
        latchClass Latches;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            main;
            app.safetyClass.startupSafety;
            app.latchClass.startupLatches;
        end

        % Button pushed function: Estop_Pushbutton
        function iEstop_Pressed(app, event)
            % ESTOP Functionality here
            app.safetyClass.setEstopState(false);
        end

        % Button pushed function: Reset_Pushbutton
        function iReset_Pressed(app, event)
            app.safetyClass.setResetState(true);
        end

        % Value changed function: Number_of_Objects
        function iNumber_Of_Objects(app, event)
            app.Number_of_Objects.Limits = [0 10];
            value = app.Number_of_Objects.Value;
            checkInput(value);
        end

        % Button pushed function: Start_Pushbutton
        function iStart_Pressed(app, event)
                app.latchClass.setRunLatch();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParaleterLabel = uilabel(app.UIFigure);
            app.ParaleterLabel.Text = 'SET SYSTEM PARAMETERS';
            app.Estop_Pushbutton = uibutton(app.UIFigure, 'push');
            app.Estop_Pushbutton.ButtonPushedFcn = createCallbackFcn(app, @iEstop_Pressed, true);
            app.Estop_Pushbutton.Tag = 'System_Estop';
            app.Estop_Pushbutton.Text = {'EMERGENCY'; 'STOP'};
            app.NumberofCansEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofCansEditFieldLabel.Text = 'Number of Cans';
            app.Number_of_Objects = uieditfield(app.UIFigure, 'numeric');
            app.Number_of_Objects.ValueChangedFcn = createCallbackFcn(app, @iNumber_Of_Objects, true);
            app.Reset_Pushbutton = uibutton(app.UIFigure, 'push');
            app.Reset_Pushbutton.ButtonPushedFcn = createCallbackFcn(app, @iReset_Pressed, true);
            app.Reset_Pushbutton.Tag = 'System_Reset';
            app.Reset_Pushbutton.Text = 'RESET';
            app.Start_Pushbutton = uibutton(app.UIFigure, 'push');
            app.Start_Pushbutton.ButtonPushedFcn = createCallbackFcn(app, @iStart_Pressed, true);
            app.Start_Pushbutton.Text = 'START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignmentApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end